classdef SegTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ML_Image                   matlab.ui.control.UIAxes
        LoadButton                 matlab.ui.control.Button
        SelectMLSegmentation       matlab.ui.control.Button
        SelectinFormSegmentation   matlab.ui.control.Button
        DrawNewSegmentationButton  matlab.ui.control.Button
        MachineLearningSegmentationLabel  matlab.ui.control.Label
        inFormSegmentationLabel    matlab.ui.control.Label
        GobacktolastcellButton     matlab.ui.control.Button
        ML_Image_2                 matlab.ui.control.UIAxes
        IF_Image                   matlab.ui.control.UIAxes
        IF_Image_2                 matlab.ui.control.UIAxes
        aSliderLabel               matlab.ui.control.Label
        aSlider                    matlab.ui.control.Slider
        bSliderLabel               matlab.ui.control.Label
        bSlider                    matlab.ui.control.Slider
        ButtonGroup                matlab.ui.container.ButtonGroup
        DAPIButton                 matlab.ui.control.RadioButton
        MembraneButton             matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        im_ML % Description
        im_IF
        nRows
        nCols
        mem_dapi_im
        mycols = [0 0 1; % DAPI
                 1 1 1];   % Membrane
       fname
       a_m = 2.5;
       a_d = 2.5;
       b_m = 200;
       b_d = 200;
       %
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            %
            app.fname = extractBetween(imagefile,'Component_Tiffs\','_component');
            % Get the ML image
            try
                for i1 = 1:2
                    im_ML_tmp(:,:,i1) = imread(imagefile, i1);
                end
                app.im_ML = im_ML_tmp;
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Finding ML Image Error');
                return;
            end            
            %
            % get the inform image name and number of layers
            %
            imagefile2 = replace(imagefile,'ML_data','inform_data');
            imagefile2 = replace(imagefile2,'_seg','_w_seg');
            %
            try 
                im_info = imfinfo(imagefile2);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Finding inForm Image Error');
                return;
            end
            %
            layers = length(im_info);
            app.nRows = im_info(1).Height;
            app.nCols = im_info(1).Width;
            %
            if layers ~= 15
               uialert(app.UIFigure, 'inForm Image Error: MISSING IMAGE LAYERS');
               return;
            end
            %
            % read INFORM IMAGE
            %
            ic = 0;
            for i1 = 11:15
                ic = ic + 1;
                im_IF_tmp1(:,:,ic) = imread(imagefile2,ic);
            end
            app.im_IF = im_IF_tmp1;
            %
            for i1 = [1, 9]
                im = imread(imagefile2, i1);
                im = im ./ max(im,[],'all');
                im_IF_tmp(:,i1) = reshape(im, [], 1);
            end
            % 
            % Display DAPI and Membrane
            %
            app.mem_dapi_im = im_IF_tmp(:,[1 9]);
            %
            disp_images(app, 1)
        end
        
        function compute_pairs()
            IF = app.im_IF(:,:,1) + app.im_IF(:,:,2);
            ML = app.im_ML(:,:,1);
            %
            % 
            %
        
        
        end
        
        function disp_images(app, nm, n1,nall)
            if nm
                ff = app.fname;
                ff= ff{1};
                if nargin > 2
                    app.UIFigure.Name = ['Segmentation Tool: ',ff, '; Cell: ', n1, 'of', nall];
                else
                    app.UIFigure.Name = ['Segmentation Tool: ',ff];
                end
            end
            %
            imshow2(app, app.ML_Image, 1)
            imshow2(app, app.ML_Image_2, 2)
            %
            imshow2(app, app.IF_Image, 1)
            imshow2(app, app.IF_Image_2, 2)
            %   
        end
        
        function imshow2(app, loc, layer)
            %
            C = app.nCols;
            R = app.nRows;
            %
            im = app.mem_dapi_im;
            mycols_n = app.mycols;
            %
            % DAPI image
            %
            ii = app.DAPIButton.Value;
            if ii
                app.a_d = .05 * app.aSlider.Value;
                app.b_d = 4 * app.bSlider.Value;
            end
            im_d =  app.b_d .* asinh(app.a_d .* im(:,1)) * mycols_n(1,:);
            %
            % Membrane image
            %
            if layer ~= 1
                ii = app.MembraneButton.Value;
                if ii
                    app.a_m = .05 * app.aSlider.Value;
                    app.b_m = 4 * app.bSlider.Value;
                end
                im =  app.b_m .* asinh(app.a_m .* im(:,2)) * mycols_n(2,:);
                %
                im_d = im + im_d;  
            end
            %
            im = reshape(im_d,R,C,3);
            im = uint8(im);
            % 
            imshow(im, 'parent',loc);
            %
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Configure image axes
            app.ML_Image.Visible = 'off';
            app.ML_Image.Colormap = gray(256);
            axis(app.ML_Image, 'image');
            %
            app.ML_Image_2.Visible = 'off';
            app.ML_Image_2.Colormap = gray(256);
            axis(app.ML_Image_2, 'image');
            %
            app.IF_Image.Visible = 'off';
            app.IF_Image.Colormap = gray(256);
            axis(app.IF_Image, 'image');
            %
            app.IF_Image_2.Visible = 'off';
            app.IF_Image_2.Colormap = gray(256);
            axis(app.IF_Image_2, 'image');
            %
            app.aSlider.Value = 50;
            app.bSlider.Value = 50;
            % Update the image and histograms
            % updateimage(app, 'peppers.png');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update thmage and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               imagefile = [p f];
               updateimage(app, imagefile);
            end
        end

        % Button pushed function: SelectinFormSegmentation
        function SelectinFormSegmentationButtonPushed(app, event)
            
        end

        % Button pushed function: SelectMLSegmentation
        function SelectMLSegmentationButtonPushed(app, event)
            
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            disp_images(app, 0)
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            disp_images(app, 0)
        end

        % Size changed function: ButtonGroup
        function ButtonGroupSizeChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentation Tool';
            app.ML_Image = uiaxes(app.UIFigure);
            app.ML_Image.XTick = [];
            app.ML_Image.XTickLabel = {'[ ]'};
            app.ML_Image.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load new image ';
            app.SelectMLSegmentation = uibutton(app.UIFigure, 'push');
            app.SelectMLSegmentation.ButtonPushedFcn = createCallbackFcn(app, @SelectMLSegmentationButtonPushed, true);
            app.SelectMLSegmentation.Text = 'Select ML Segmentation';
            app.SelectinFormSegmentation = uibutton(app.UIFigure, 'push');
            app.SelectinFormSegmentation.ButtonPushedFcn = createCallbackFcn(app, @SelectinFormSegmentationButtonPushed, true);
            app.SelectinFormSegmentation.Text = 'Select inForm Segmentation';
            app.DrawNewSegmentationButton = uibutton(app.UIFigure, 'push');
            app.DrawNewSegmentationButton.Text = 'Draw New Segmentation';
            app.MachineLearningSegmentationLabel = uilabel(app.UIFigure);
            app.MachineLearningSegmentationLabel.Text = 'Machine Learning Segmentation';
            app.inFormSegmentationLabel = uilabel(app.UIFigure);
            app.inFormSegmentationLabel.Text = 'inForm Segmentation';
            app.GobacktolastcellButton = uibutton(app.UIFigure, 'push');
            app.GobacktolastcellButton.Text = 'Go back to last cell';
            app.ML_Image_2 = uiaxes(app.UIFigure);
            app.ML_Image_2.XTick = [];
            app.ML_Image_2.XTickLabel = {'[ ]'};
            app.ML_Image_2.YTick = [];
            app.IF_Image = uiaxes(app.UIFigure);
            app.IF_Image.XTick = [];
            app.IF_Image.XTickLabel = {'[ ]'};
            app.IF_Image.YTick = [];
            app.IF_Image_2 = uiaxes(app.UIFigure);
            app.IF_Image_2.XTick = [];
            app.IF_Image_2.XTickLabel = {'[ ]'};
            app.IF_Image_2.YTick = [];
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ButtonGroupSizeChanged, true);
            app.DAPIButton = uiradiobutton(app.ButtonGroup);
            app.DAPIButton.Text = 'DAPI';
            app.DAPIButton.Value = true;
            app.MembraneButton = uiradiobutton(app.ButtonGroup);
            app.MembraneButton.Text = 'Membrane';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end